classdef DialogApp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotUIFigure  matlab.ui.Figure
        UIAxes        matlab.ui.control.UIAxes
        UIAxes_2      matlab.ui.control.UIAxes
        hightSlider   matlab.ui.control.Slider
        EditField     matlab.ui.control.EditField
        EditField_2   matlab.ui.control.EditField
        EditField_3   matlab.ui.control.EditField
        unitumLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp 
        
        DialogAppFigure1 %Dialogapp for showing the contact friction
        DialogAppFigure2 %Dialogapp for showing the density of contact patches
        DialogAppFigure3 %Dialogapp for showing the PSD        
        DialogAppFigure4 %Dialogapp for showing the density of contact patches
        DialogAppFigure5 %Dialogapp for showing the density of contact patches
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Z1, Length, cengshu)
            app.CallingApp = mainapp;
            

            global n;
            n = length(Z1);
            
            N = [n n]; % size in pixels of image
            global X;
            global Y;
            [X,Y] = ndgrid(1:N(1),1:N(2));
            
            x = 1:n;
            y = 1:n;
            [x,y]=meshgrid(x,y);
            xi = 1:0.1:n;
            yi = 1:0.1:n;
            [xi,yi]=meshgrid(xi,yi);
            zi=interp2(x,y,Z1,xi,yi,'cubic');
            
            %setting edit&slider
            zmaxmax = max(max(Z1));
            zminmin = 0;

            app.hightSlider.Limits = [zminmin*1e6,zmaxmax*1e6];
            app.hightSlider.Value = zminmin*1e6;
            
            str1 = num2str(zminmin*1e6);
            app.EditField.Value = str1;
            str2 = num2str(zmaxmax*1e6);
            app.EditField_3.Value = str2;
            
            %cengshu = 20;
            h = linspace(0,zmaxmax,cengshu); %
            M = length(h);
            
            contour(app.UIAxes,xi,yi,zi,cengshu);
            colorbar(app.UIAxes);
            
            app.UIAxes.XAxis.Limits = [-1 n+1];
            app.UIAxes.YAxis.Limits = [-1 n+1];
            
        end

        % Close request function: PlotUIFigure
        function DialogAppCloseRequest(app, event)
            app.CallingApp.OKButton.Enable = 'on';
            
            app.CallingApp.ForcepredictionButton.Enable = 'on';
            app.CallingApp.ContactfrictionButton.Enable = 'on';
            app.CallingApp.DensityofcontactpatchesButton.Enable = 'on';
            app.CallingApp.PowerspectraldensityButton.Enable = 'on';
            app.CallingApp.MomentsButton.Enable = 'on';
            
            delete(app.DialogAppFigure1)
            delete(app.DialogAppFigure2)
            delete(app.DialogAppFigure3)           
            delete(app.DialogAppFigure4)
            delete(app.DialogAppFigure5)
            
            %app.CallingApp.Currenthight = 0;
            
            delete(app);
        end

        % Value changed function: hightSlider
        function hightSliderValueChanged(app, event)
            value = app.hightSlider.Value*1e-6; %unit m
            global n;
            global Z1;
            global X;
            global Y;
            idx = (Z1-value)>=0*min(diff(Z1(:)));
            
            %axes(app.axes4);
            %cla reset
            
            app.UIAxes_2.XAxis.Limits = [-1 n+1];
            app.UIAxes_2.YAxis.Limits = [-1 n+1];
            scatter(app.UIAxes_2,Y(idx),X(idx),'.');
            
            str4 = num2str(value*1e6);
            app.EditField_2.Value=str4;
            
            %app.hightSlider.Enable = 'off';
            %app.EditField_2.Enable = 'off';
            
            updatehight(app.CallingApp,value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            str = app.EditField_2.Value;
            
            value = str2double(str)*1e-6;
            
            global n;
            global Z1;
            global X;
            global Y;
            idx = (Z1-value)>=0*min(diff(Z1(:)));
            
            %axes(app.axes4);
            %cla reset
            
            app.UIAxes_2.XAxis.Limits = [-1 n+1];
            app.UIAxes_2.YAxis.Limits = [-1 n+1];
            scatter(app.UIAxes_2,Y(idx),X(idx),'.');
            
            app.hightSlider.Value = value*1e6;
            
            %app.hightSlider.Enable = 'off';
            %app.EditField_2.Enable = 'off';
                        
            updatehight(app.CallingApp,value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotUIFigure = uifigure('Visible', 'off');
            app.PlotUIFigure.Name = 'Plot';
            app.PlotUIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.UIAxes = uiaxes(app.PlotUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes_2 = uiaxes(app.PlotUIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.hightSlider = uislider(app.PlotUIFigure);
            app.hightSlider.ValueChangedFcn = createCallbackFcn(app, @hightSliderValueChanged, true);
            app.EditField = uieditfield(app.PlotUIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.PlotUIFigure, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.PlotUIFigure, 'text');
            app.EditField_3.Editable = 'off';
            app.unitumLabel = uilabel(app.PlotUIFigure);
            app.unitumLabel.Text = 'unit:um';
            % Show the figure after all components are created
            app.PlotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogApp1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotUIFigure)
        end
    end
end