classdef DialogAppError < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ERRORUIFigure  matlab.ui.Figure
        OKButton       matlab.ui.control.Button
        InputfileerroneouslyPleasecheckthefileaddressanddatainfileLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.CallingApp.OKButton.Enable = 'on';
            
            app.CallingApp.ForcepredictionButton.Enable = 'on';
            app.CallingApp.ContactfrictionButton.Enable = 'on';
            app.CallingApp.DensityofcontactpatchesButton.Enable = 'on';
            app.CallingApp.PowerspectraldensityButton.Enable = 'on';
            app.CallingApp.MomentsButton.Enable = 'on';
            
            delete(app);
            
        end

        % Close request function: ERRORUIFigure
        function ERRORUIFigureCloseRequest(app, event)
            app.CallingApp.OKButton.Enable = 'on';
            
            app.CallingApp.ForcepredictionButton.Enable = 'on';
            app.CallingApp.ContactfrictionButton.Enable = 'on';
            app.CallingApp.DensityofcontactpatchesButton.Enable = 'on';
            app.CallingApp.PowerspectraldensityButton.Enable = 'on';
            app.CallingApp.MomentsButton.Enable = 'on';
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ERRORUIFigure = uifigure('Visible', 'off');
            app.ERRORUIFigure.Name = 'ERROR';
            app.ERRORUIFigure.CloseRequestFcn = createCallbackFcn(app, @ERRORUIFigureCloseRequest, true);
            app.OKButton = uibutton(app.ERRORUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.InputfileerroneouslyPleasecheckthefileaddressanddatainfileLabel = uilabel(app.ERRORUIFigure);
            app.InputfileerroneouslyPleasecheckthefileaddressanddatainfileLabel.Text = {'Input file erroneously! '; 'Please check the file address and data in file!'};
            % Show the figure after all components are created
            app.ERRORUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppError(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ERRORUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ERRORUIFigure)
        end
    end
end