classdef DialogAppFig1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ContactFrictionUIFigure  matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Az, dz, m00, Length, hight)
            app.CallingApp = mainapp;
            
            plot( app.UIAxes, dz/sqrt(m00) , Az/Length^2 );
            hold(app.UIAxes,"on");
            maxA = max(Az/Length^2);
            L = ones(1,50)*(hight*1e3/sqrt(m00));
            plot(app.UIAxes,L,linspace(0,maxA,50));
        end

        % Close request function: ContactFrictionUIFigure
        function DialogAppFigure1CloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ContactFrictionUIFigure = uifigure('Visible', 'off');
            app.ContactFrictionUIFigure.Name = 'Contact Friction';
            app.ContactFrictionUIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppFigure1CloseRequest, true);
            app.UIAxes = uiaxes(app.ContactFrictionUIFigure);
            title(app.UIAxes, 'Contact Friction')
            xlabel(app.UIAxes, 'surface seperation')
            ylabel(app.UIAxes, 'contact friction')
            % Show the figure after all components are created
            app.ContactFrictionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppFig1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ContactFrictionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ContactFrictionUIFigure)
        end
    end
end