classdef DialogAppFig2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DensityofcontactpatchesUIFigure  matlab.ui.Figure
        UIAxes  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, nz, dz, m00, Length, hight)
            app.CallingApp = mainapp;
            
            plot( app.UIAxes,dz/sqrt(m00) , nz);   %quantity of contact patches unit number
            %plot( app.UIAxes,dz/sqrt(m00) , nz/Length^2 ); %density of contact patchesu
            %unit number/mm^2
            hold(app.UIAxes,"on");
            maxN = max(nz);
            %maxN = max(nz/Length^2);
            L = ones(1,50)*(hight*1e3/sqrt(m00));
            plot(app.UIAxes,L,linspace(0,maxN,50));
        end

        % Close request function: DensityofcontactpatchesUIFigure
        function DialogAppFigure2CloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DensityofcontactpatchesUIFigure = uifigure('Visible', 'off');
            app.DensityofcontactpatchesUIFigure.Name = 'Density of contact patches';
            app.DensityofcontactpatchesUIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppFigure2CloseRequest, true);
            app.UIAxes = uiaxes(app.DensityofcontactpatchesUIFigure);
            title(app.UIAxes, 'Density of contact patches')
            xlabel(app.UIAxes, 'surface seperation')
            ylabel(app.UIAxes, 'quantity of contact patches')
            % Show the figure after all components are created
            app.DensityofcontactpatchesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppFig2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DensityofcontactpatchesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DensityofcontactpatchesUIFigure)
        end
    end
end