classdef DialogAppFig3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        powerspectraldensityUIFigure  matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, fx, Psd, Nx)
            app.CallingApp = mainapp;
            
            plot(app.UIAxes,fx(Nx/2+1:Nx)*1e3*2*pi,Psd(Nx/2+1,Nx/2+1:Nx)/1e36)  % display transform magnitude            
            app.UIAxes.XScale = 'log';
            app.UIAxes.YScale = 'log';
            
        end

        % Close request function: powerspectraldensityUIFigure
        function DialogAppFigure3CloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.powerspectraldensityUIFigure = uifigure('Visible', 'off');
            app.powerspectraldensityUIFigure.Name = 'power spectral density';
            app.powerspectraldensityUIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppFigure3CloseRequest, true);
            app.UIAxes = uiaxes(app.powerspectraldensityUIFigure);
            title(app.UIAxes, 'power spectral density')
            xlabel(app.UIAxes, 'q/m^{-1}')
            ylabel(app.UIAxes, 'PSD(power spectral density) /m^4')
            % Show the figure after all components are created
            app.powerspectraldensityUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppFig3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.powerspectraldensityUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.powerspectraldensityUIFigure)
        end
    end
end