classdef DialogAppFig5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MomentUIFigure       matlab.ui.Figure
        m00EditFieldLabel    matlab.ui.control.Label
        m00EditField         matlab.ui.control.EditField
        m02EditFieldLabel    matlab.ui.control.Label
        m02EditField         matlab.ui.control.EditField
        m20EditFieldLabel    matlab.ui.control.Label
        m20EditField         matlab.ui.control.EditField
        m04EditFieldLabel    matlab.ui.control.Label
        m04EditField         matlab.ui.control.EditField
        m40EditFieldLabel    matlab.ui.control.Label
        m40EditField         matlab.ui.control.EditField
        m00EditFieldLabel_2  matlab.ui.control.Label
        m00EditFieldLabel_3  matlab.ui.control.Label
        m00EditFieldLabel_4  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, m00, m02, m20, m04, m40)
            app.CallingApp = mainapp;
            
            app.m00EditField.Value = num2str(m00*(1e3)^2);
            app.m02EditField.Value = num2str(m02);
            app.m20EditField.Value = num2str(m20);
            app.m04EditField.Value = num2str(m04/(1e3)^2);
            app.m40EditField.Value = num2str(m40/(1e3)^2);
            
        end

        % Close request function: MomentUIFigure
        function DialogAppFigure5CloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MomentUIFigure = uifigure('Visible', 'off');
            app.MomentUIFigure.Name = 'Moment';
            app.MomentUIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppFigure5CloseRequest, true);
            app.m00EditFieldLabel = uilabel(app.MomentUIFigure);
            app.m00EditFieldLabel.Text = 'm00';
            app.m00EditField = uieditfield(app.MomentUIFigure, 'text');
            app.m00EditField.Editable = 'off';
            app.m02EditFieldLabel = uilabel(app.MomentUIFigure);
            app.m02EditFieldLabel.Text = 'm02';
            app.m02EditField = uieditfield(app.MomentUIFigure, 'text');
            app.m02EditField.Editable = 'off';
            app.m20EditFieldLabel = uilabel(app.MomentUIFigure);
            app.m20EditFieldLabel.Text = 'm20';
            app.m20EditField = uieditfield(app.MomentUIFigure, 'text');
            app.m20EditField.Editable = 'off';
            app.m04EditFieldLabel = uilabel(app.MomentUIFigure);
            app.m04EditFieldLabel.Text = 'm04';
            app.m04EditField = uieditfield(app.MomentUIFigure, 'text');
            app.m04EditField.Editable = 'off';
            app.m40EditFieldLabel = uilabel(app.MomentUIFigure);
            app.m40EditFieldLabel.Text = 'm40';
            app.m40EditField = uieditfield(app.MomentUIFigure, 'text');
            app.m40EditField.Editable = 'off';
            app.m00EditFieldLabel_2 = uilabel(app.MomentUIFigure);
            app.m00EditFieldLabel_2.Text = 'um^2';
            app.m00EditFieldLabel_3 = uilabel(app.MomentUIFigure);
            app.m00EditFieldLabel_3.Text = 'um^-2';
            app.m00EditFieldLabel_4 = uilabel(app.MomentUIFigure);
            app.m00EditFieldLabel_4.Text = 'um^-2';
            % Show the figure after all components are created
            app.MomentUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppFig5(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MomentUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MomentUIFigure)
        end
    end
end