classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RoughSurfaceImitateUIFigure     matlab.ui.Figure
        FileFormButtonGroup             matlab.ui.container.ButtonGroup
        RandomButton                    matlab.ui.control.ToggleButton
        MatrixofhightButton             matlab.ui.control.ToggleButton
        NanoMapButton                   matlab.ui.control.ToggleButton
        EditField                       matlab.ui.control.EditField
        ContactfrictionButton           matlab.ui.control.StateButton
        DensityofcontactpatchesButton   matlab.ui.control.StateButton
        PowerspectraldensityButton      matlab.ui.control.StateButton
        ForcepredictionButton           matlab.ui.control.StateButton
        OKButton                        matlab.ui.control.Button
        lengthofMatrizEditFieldLabel_2  matlab.ui.control.Label
        lengthofMatrizEditFieldLabel_3  matlab.ui.control.Label
        lengthofMatrizEditField         matlab.ui.control.NumericEditField
        MomentsButton                   matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        DialogApp % DialogApp for showing the surface
        DialogAppFigure1 %Dialogapp for showing the contact friction
        DialogAppFigure2 %Dialogapp for showing the density of contact patches
        DialogAppFigure3 %Dialogapp for showing the PSD        
        DialogAppFigure4 %Dialogapp for showing the density of contact patches
        DialogAppFigure5 %Dialogapp for showing the density of contact patches
        
        DialogApp_Error
        
        Currenthight = 0;
    end
    
    
    methods (Access = public)
        
        function updatehight(app,hight)
            app.Currenthight = hight;
            delete(app.DialogAppFigure1);
            delete(app.DialogAppFigure2);
            
            global Az nz dz m00 Length;
            if app.ContactfrictionButton.Value == 1
                app.DialogAppFigure1 = DialogAppFig1(app,Az,dz,m00,Length,app.Currenthight);
            end
            if app.DensityofcontactpatchesButton.Value == 1
                app.DialogAppFigure2 = DialogAppFig2(app,nz,dz,m00,Length,app.Currenthight);   
            end
            
            app.Currenthight = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.OKButton.Enable = 'off';
            app.ForcepredictionButton.Enable = 'off';
            app.ContactfrictionButton.Enable = 'off';
            app.DensityofcontactpatchesButton.Enable = 'off';
            app.PowerspectraldensityButton.Enable = 'off';
            app.MomentsButton.Enable = 'off';
            
            CurrentFileform = [app.RandomButton.Value app.MatrixofhightButton.Value app.NanoMapButton.Value]*([1 2 3]');
            CurrentFileStr = app.EditField.Value;%not input file
            CurrentLength = app.lengthofMatrizEditField.Value;
            CurrentSection = [app.ContactfrictionButton.Value app.DensityofcontactpatchesButton.Value app.PowerspectraldensityButton.Value app.ForcepredictionButton.Value app.MomentsButton.Value];
            
            errorstate = 0;
            
            %%pre
            str = CurrentFileStr;
            global Z1;
            global Length;
            
            switch CurrentFileform
                case 1 %random surface
                    Length = 200e-6; %unit m
                    [Z1,PixelWidth,PSD] = artificial_surf(1e-6,0.8,Length,256,256,2*pi/200e-6*256*0.01); %unit m
                case 2 %input matriz of hight
                    Length = CurrentLength*1e-6; %unit m
                    
                    try
                        Z1 = load_surfacefile(str)*1e-6; %unit m
                    catch ME
                        app.OKButton.Enable = 'off';
                        app.DialogApp_Error = DialogAppError(app);
                        errorstate = ~(errorstate);
                    end
                    
                case 3 %input .asc file
                    
                    try
                        Data= textread(str,'%s', 'delimiter', '\t'); 
                        Xlength = str2double(Data{7,1}(14:length(Data{7,1})));
                        Ylength = str2double(Data{8,1}(14:length(Data{8,1})));
                        Xpixel = str2num(Data{5,1}(14:length(Data{5,1})));
                        Ypixel = str2num(Data{6,1}(14:length(Data{6,1})));
                        unitstr = Data{11,1}(12:length(Data{11,1}));
                        C = zeros(Xpixel,Ypixel);
                    
                        for i = 1:Xpixel
                            for j = 1:Ypixel
                                C(i,j) = str2double(Data{16+j+(i-1)*Xpixel,1});
                            end
                        end
                    
                        Length = Xlength;
                    
                        switch unitstr
                            case 'nm'
                                unit = 1e-9;
                            case 'um'
                                unit = 1e-6;
                            case 'mm'
                                unit = 1e-3;
                            case 'cm'
                                unit = 1e-2;
                            case 'm'
                                unit = 1;
                        end
                    
                        Z1 = C*unit; %unit m
                    
                    catch ME
                        app.OKButton.Enable = 'off';
                        app.DialogApp_Error = DialogAppError(app);
                        errorstate = ~(errorstate);
                    end
                    
            end
            
            if errorstate == 0
            
            %% Z1 & Length      

            global cengshu Az nz dz m00;
                        
            cengshu = 100;
            
            %%PSD
            Length = Length*1e3; %unit mm
            z = Z1*1e9; %unit nm
            
            z_mean = mean(mean(z));
            z = z - z_mean;
            
            [Nx,Ny]=   size(z);                         %%-----Pixel Number
            step   =   Length/Nx;                       %%-----min gap mm
            
            dx=0:step:(Nx-1)*step;
            dy=0:step:(Nx-1)*step;
            
            Fs     =   1/step;                          %%-----frequence
            MEAN   =   mean(mean(z));                   %%-----mean hight
            EE     =   1/(Nx*Ny)*sum(sum((z-MEAN).^2)); %%-----�����߶�
            
            dx     =   Length/Nx;
            dy     =   Length/Ny;
            
            g      =   z-MEAN;
            G0     =   fft2(g);
            G      =   fftshift(G0);
            ABSFF  =   abs(G)/(Nx*Ny);  %  fft  ������
            ANGFF  =   angle(G);
            
            fx = -1/(2*dx):1/(Nx*dx):1/(2*dx)-(1/(Nx*dx)); % x freq coords
            fy = -1/(2*dy):1/(Ny*dy):1/(2*dy)-(1/(Ny*dy)); % y freq coords
            
            P      =   ABSFF.^2;                                %  Power(����)  ������
            Psd    =   P/((2*pi/(Nx*dx*10^6))*(2*pi/(Ny*dy*10^6)));   %  Power Spectrum Density (PSD)  ������
            
            %% Psd
            AMP = 1;         % 1:mm   10^(3):um   10^(6):nm
            Psd = Psd * 10^(-24) / AMP^4;
            fx  = fx  * AMP;
            fy  = fy  * AMP;
            dx  = dx  / AMP;
            dy  = dy  / AMP;
            format longe
            
            m00 = 0; %unit mm
            m11 = 0; m13 = 0; m31 = 0;
            m20 = 0; m02 = 0;
            m22 = 0; m04 = 0; m40 = 0;
            
            
            for ii = 1 : Ny
                for jj = 1 : Nx
                    m00 = m00 + Psd(ii,jj) * (2*pi*fx(jj))^0 * (2*pi*fy(ii))^0 * ( 2*pi/(Nx*dx) * 2*pi/(Ny*dy) );
                    m11 = m11 + Psd(ii,jj) * (2*pi*fx(jj))^1 * (2*pi*fy(ii))^1 * ( 2*pi/(Nx*dx) * 2*pi/(Ny*dy) );
                    m13 = m13 + Psd(ii,jj) * (2*pi*fx(jj))^1 * (2*pi*fy(ii))^3 * ( 2*pi/(Nx*dx) * 2*pi/(Ny*dy) );
                    m31 = m31 + Psd(ii,jj) * (2*pi*fx(jj))^3 * (2*pi*fy(ii))^1 * ( 2*pi/(Nx*dx) * 2*pi/(Ny*dy) );
                    m20 = m20 + Psd(ii,jj) * (2*pi*fx(jj))^2 * (2*pi*fy(ii))^0 * ( 2*pi/(Nx*dx) * 2*pi/(Ny*dy) );
                    m02 = m02 + Psd(ii,jj) * (2*pi*fx(jj))^0 * (2*pi*fy(ii))^2 * ( 2*pi/(Nx*dx) * 2*pi/(Ny*dy) );
                    m22 = m22 + Psd(ii,jj) * (2*pi*fx(jj))^2 * (2*pi*fy(ii))^2 * ( 2*pi/(Nx*dx) * 2*pi/(Ny*dy) );
                    m40 = m40 + Psd(ii,jj) * (2*pi*fx(jj))^4 * (2*pi*fy(ii))^0 * ( 2*pi/(Nx*dx) * 2*pi/(Ny*dy) );
                    m04 = m04 + Psd(ii,jj) * (2*pi*fx(jj))^0 * (2*pi*fy(ii))^4 * ( 2*pi/(Nx*dx) * 2*pi/(Ny*dy) );
                end
            end
            
            alpha  =   m00*m40/m20^2;
            omega  =   sqrt(m20/pi);
            
            if CurrentSection(3) == 1
                app.DialogAppFigure3 = DialogAppFig3(app,fx,Psd,Nx);                 
            end
            
            if CurrentSection(5) == 1
                app.DialogAppFigure5 = DialogAppFig5(app,m00,m02,m20,m04,m40);                 
            end
            
            %% A(z)
            
            
            z  = z * 1e-6;           % unit mm
            
            dz = linspace(0,max(max(z)),cengshu);
            if CurrentSection(1)||CurrentSection(4) == 1
                for ii = 1 : cengshu
                    Az(ii) = sum(sum( z >= dz(ii) )) * step^2; %unit mm^2
                end
            end
            
            if CurrentSection(1) == 1
                app.DialogAppFigure1 = DialogAppFig1(app,Az,dz,m00,Length,app.Currenthight);                
            end
            
            %% n(z)
            if CurrentSection(2)||CurrentSection(4) == 1
                for ii = 1 : cengshu
                    z_modified = z;
                    z_modified( find(z_modified >= dz(ii)) ) = dz(ii);
            
                    [row,col]  = find(z_modified >= dz(ii));
                    All_B      = row+col.*1i;
                    [max_num]  = spot_sort(All_B, z_modified);
            
                    nz(ii)   = size(max_num,1);
                 end
            end
            
            if CurrentSection(2) == 1
                app.DialogAppFigure2 = DialogAppFig2(app,nz,dz,m00,Length,app.Currenthight);
            end
            
            %% force
            if CurrentSection(4) == 1
                az = Az./nz; 
            
                dz2 = dz(2:end);
                dz2(max(size(dz2))+1) = max(max(z));
                dP = nz.*az.^(1/2).*(dz2-dz);
                for ii = 1 : cengshu
                    P_normal(ii) = 2/Length^2/sqrt(pi) * sum(dP(ii+1:end));
                    %P_normal(ii) = 2/Length^2/sqrt(pi)/omega * sum(dP(ii+1:end));
                end
                
                app.DialogAppFigure4 = DialogAppFig4(app,Az,P_normal,Length);
            end
            
            app.DialogApp = DialogApp1(app,Z1,Length,cengshu);
            
            end
        end

        % Close request function: RoughSurfaceImitateUIFigure
        function MainAppCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app.DialogAppFigure1)
            delete(app.DialogAppFigure2)
            delete(app.DialogAppFigure3)           
            delete(app.DialogAppFigure4)
            delete(app.DialogAppFigure5)
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RoughSurfaceImitateUIFigure = uifigure('Visible', 'off');
            app.RoughSurfaceImitateUIFigure.Name = 'Rough Surface Imitate';
            app.RoughSurfaceImitateUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.FileFormButtonGroup = uibuttongroup(app.RoughSurfaceImitateUIFigure);
            app.FileFormButtonGroup.Title = 'FileForm';
            app.RandomButton = uitogglebutton(app.FileFormButtonGroup);
            app.RandomButton.Text = 'Random';
            app.RandomButton.Value = true;
            app.MatrixofhightButton = uitogglebutton(app.FileFormButtonGroup);
            app.MatrixofhightButton.Text = 'Matrix of hight';
            app.NanoMapButton = uitogglebutton(app.FileFormButtonGroup);
            app.NanoMapButton.Text = 'NanoMap';
            app.EditField = uieditfield(app.RoughSurfaceImitateUIFigure, 'text');
            app.ContactfrictionButton = uibutton(app.RoughSurfaceImitateUIFigure, 'state');
            app.ContactfrictionButton.Text = 'Contact friction';
            app.DensityofcontactpatchesButton = uibutton(app.RoughSurfaceImitateUIFigure, 'state');
            app.DensityofcontactpatchesButton.Text = {'Density of '; 'contact patches'};
            app.PowerspectraldensityButton = uibutton(app.RoughSurfaceImitateUIFigure, 'state');
            app.PowerspectraldensityButton.Text = {'Power spectral '; 'density'};
            app.ForcepredictionButton = uibutton(app.RoughSurfaceImitateUIFigure, 'state');
            app.ForcepredictionButton.Text = 'Force prediction';
            app.OKButton = uibutton(app.RoughSurfaceImitateUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.lengthofMatrizEditFieldLabel_2 = uilabel(app.RoughSurfaceImitateUIFigure);
            app.lengthofMatrizEditFieldLabel_2.Text = 'unit:um';
            app.lengthofMatrizEditFieldLabel_3 = uilabel(app.RoughSurfaceImitateUIFigure);
            app.lengthofMatrizEditFieldLabel_3.Text = 'length of Matriz';
            app.lengthofMatrizEditField = uieditfield(app.RoughSurfaceImitateUIFigure, 'numeric');
            app.MomentsButton = uibutton(app.RoughSurfaceImitateUIFigure, 'state');
            app.MomentsButton.Text = 'Moments';
            % Show the figure after all components are created
            app.RoughSurfaceImitateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RoughSurfaceImitateUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RoughSurfaceImitateUIFigure)
        end
    end
end